classdef PID_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        PortDropDownLabel        matlab.ui.control.Label
        PortDropDown             matlab.ui.control.DropDown
        ConnectButton            matlab.ui.control.Button
        ProportionalSliderLabel  matlab.ui.control.Label
        ProportionalSlider       matlab.ui.control.Slider
        ProportionalEdit         matlab.ui.control.NumericEditField
        IntegralEdit             matlab.ui.control.NumericEditField
        IntegralSliderLabel      matlab.ui.control.Label
        IntegralSlider           matlab.ui.control.Slider
        DerivativeEdit           matlab.ui.control.NumericEditField
        DerivativeSliderLabel    matlab.ui.control.Label
        DerivativeSlider         matlab.ui.control.Slider
        AngleEdit                matlab.ui.control.NumericEditField
        AngleSliderLabel         matlab.ui.control.Label
        AngleSlider              matlab.ui.control.Slider
        RestartButton            matlab.ui.control.Button
        StartStopSwitch          matlab.ui.control.Switch
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        %timers
        generic_upd_tmr;
        %data
        IMUport= [];  
        IMU= [];
        stateOnOff = 0
        angleTarget = 90;
        initialTime = 0;
        anglePrev = 0;
        angle = 0;
        P = 0;
        I = 0;
        D = 0;
        PIDSum = 0;
        timeSample = 0;
        uartCallbackCnt = 0;
        uartCallbackCntPrev = 0;
        prevP = 0;
        prevI = 0;
        prevD = 0;
        %graphs
        angleTargetLine=[];
        angleLine = []; PLine = []; ILine = []; DLine = []; PIDSumLine = []; 
        drawingState = false;
    end
    
    methods (Access = private)

        function form_upd(app)
            
            if app.uartCallbackCnt ~= app.uartCallbackCntPrev
                app.drawingState = true;
                app.uartCallbackCntPrev = app.uartCallbackCnt;
                
                xlim(app.UIAxes, [app.timeSample(end)-10 app.timeSample(end)]) 
                
                if isempty(app.angleLine)
                    app.angleLine = animatedline(app.UIAxes, 'Color', 'b', 'LineWidth',1.5);
                else
                   addpoints(app.angleLine, app.timeSample, app.angle)
                end
                
                if isempty(app.PLine)
                    app.PLine = animatedline(app.UIAxes_2, 'Color', 'r', 'LineWidth',1.5);
                    app.ILine = animatedline(app.UIAxes_2, 'Color', 'g', 'LineWidth',1.5);
                    app.DLine = animatedline(app.UIAxes_2, 'Color', 'b', 'LineWidth',1.5);
                    app.PIDSumLine = animatedline(app.UIAxes_2, 'Color', 'k', 'LineWidth',1.5);
                else
                    addpoints(app.PLine, app.timeSample, app.P)
                    addpoints(app.ILine, app.timeSample, app.I)
                    addpoints(app.DLine, app.timeSample, app.D)
                    addpoints(app.PIDSumLine, app.timeSample, app.PIDSum)
                end
                
                app.P = [];
                app.I = [];
                app.D = [];
                app.PIDSum = [];
                app.angle = [];
                app.timeSample = [];
                app.drawingState = false;
                
            end
            drawnow
            
        end      
        
        function getIMUdata(app, src, ~ )
            if app.drawingState == false
                data=read(src, 5, "int16");
                app.angle = [app.angle data(1)/200];
                app.P = [app.P data(2)/1000];
                app.I = [app.I data(3)/1000];
                app.D = [app.D data(4)/1000];
                app.PIDSum = [app.PIDSum data(5)/1000];
                
                app.timeSample = [app.timeSample toc(app.initialTime)];
                app.uartCallbackCnt = app.uartCallbackCnt + 1;
            end
        end        

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AngleSlider.Value = app.angleTarget;
            app.AngleEdit.Value = app.angleTarget;
            app.angleTargetLine = yline(app.UIAxes,app.angleTarget, 'r');
            linkaxes([app.UIAxes, app.UIAxes_2], 'x');
            
            %create gui upd timer            
            app.generic_upd_tmr = timer( ...
                'ExecutionMode','fixedRate', ...
                'BusyMode', 'queue', ...
                'Period',0.2, ...
                'TimerFcn',@(~,~)app.form_upd() ...
            );            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {649, 649};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {380, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Drop down opening function: PortDropDown
        function PortDropDownOpening(app, event)
            app.PortDropDown.Items = seriallist;
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            app.IMUport = app.PortDropDown.Value;
            app.ConnectButton.Enable = 'on';
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if (app.stateOnOff == 0) 
                app.stateOnOff = 1;
                app.ConnectButton.Text = "Disconnect";
                app.StartStopSwitch.Value = "Stop";
                app.StartStopSwitch.Enable = "on";
                app.RestartButton.Enable = "on";
                app.IMU = serialport(app.IMUport,115200,"Timeout",15);
                app.PortDropDown.Enable = 'off';
                app.AngleEdit.Enable = 'on';
                app.AngleSlider.Enable = 'on';
                app.ProportionalEdit.Enable = 'on';
                app.ProportionalSlider.Enable = 'on';
                app.IntegralEdit.Enable = 'on';
                app.IntegralSlider.Enable = 'on';
                app.DerivativeEdit.Enable = 'on';
                app.DerivativeSlider.Enable = 'on';
                app.initialTime = tic;
                start(app.generic_upd_tmr);
                configureCallback(app.IMU, "byte", 10, @(src, evt) app.getIMUdata(src, evt) );
                if ~isempty(app.angleLine)
                    clearpoints(app.PLine)
                    clearpoints(app.ILine)
                    clearpoints(app.DLine)
                    clearpoints(app.angleLine)
                    clearpoints(app.PIDSumLine)                                    
                end
            else
                app.stateOnOff = 0;
                app.ConnectButton.Text = "Connect";
                delete(app.IMU);
                app.PortDropDown.Enable = 'on';
                app.StartStopSwitch.Enable = "off";
                app.StartStopSwitch.Value = "Stop";
                app.RestartButton.Enable = "off";
                app.AngleEdit.Enable = 'off';
                app.AngleSlider.Enable = 'off';
                app.ProportionalEdit.Enable = 'off';
                app.ProportionalSlider.Enable = 'off';
                app.IntegralEdit.Enable = 'off';
                app.IntegralSlider.Enable = 'off';
                app.DerivativeEdit.Enable = 'off';
                app.DerivativeSlider.Enable = 'off';
                stop(app.generic_upd_tmr);
            end             
        end

        % Value changing function: AngleSlider
        function AngleSliderValueChanging(app, event)
            changingValue = event.Value;
            app.angleTarget = changingValue;
            app.AngleEdit.Value = changingValue;
            app.angleTargetLine.Value = changingValue;            
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.generic_upd_tmr);
            clear app.generic_upd_tmr;
            delete(app.generic_upd_tmr)
            if ~isempty(app.IMU)
                delete(app.IMU)
            end
            delete(app)  
        end

        % Value changed function: AngleEdit
        function AngleEditValueChanged(app, event)
            value = app.AngleEdit.Value;
            app.angleTarget = value;
            app.AngleSlider.Value = value;
            app.angleTargetLine.Value = value;
            str = sprintf("30%3.0f", value);
            write(app.IMU, str, "char");
            
        end

        % Value changing function: ProportionalSlider
        function ProportionalSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ProportionalEdit.Value = changingValue;
            
        end

        % Value changed function: ProportionalEdit
        function ProportionalEditValueChanged(app, event)
            value = app.ProportionalEdit.Value;
            app.ProportionalSlider.Value = value;
            str = sprintf("31%1.2f", value);
            write(app.IMU, str, "char");
            
        end

        % Value changing function: IntegralSlider
        function IntegralSliderValueChanging(app, event)
            changingValue = event.Value;
            app.IntegralEdit.Value = changingValue;
        end

        % Value changed function: IntegralEdit
        function IntegralEditValueChanged(app, event)
            value = app.IntegralEdit.Value;
            app.IntegralSlider.Value = value;
            str = sprintf("32%1.2f", value);
            write(app.IMU, str, "char");
            
        end

        % Value changing function: DerivativeSlider
        function DerivativeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.DerivativeEdit.Value = changingValue;
            
        end

        % Value changed function: DerivativeEdit
        function DerivativeEditValueChanged(app, event)
            value = app.DerivativeEdit.Value;
            app.DerivativeSlider.Value = value;
            str = sprintf("33%1.2f", value);
            write(app.IMU, str, "char");
            
        end

        % Value changed function: StartStopSwitch
        function StartStopSwitchValueChanged(app, event)
            value = app.StartStopSwitch.Value;
            if value == "Run"
               write(app.IMU, "0", "char"); 
            else
               write(app.IMU, "1", "char"); 
            end
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            write(app.IMU, "2", "char");
            clearpoints(app.PLine)
            clearpoints(app.ILine)
            clearpoints(app.DLine)
            clearpoints(app.angleLine)
            clearpoints(app.PIDSumLine)
%             app.angleTargetLine = yline(app.UIAxes,app.angleTarget, 'r');
            
        end

        % Value changed function: AngleSlider
        function AngleSliderValueChanged(app, event)
            value = app.AngleSlider.Value;
            str = sprintf("30%3.2f", value);
            write(app.IMU, str, "char");
            
        end

        % Value changed function: ProportionalSlider
        function ProportionalSliderValueChanged(app, event)
            value = app.ProportionalSlider.Value;
            str = sprintf("31%1.2f", value);
            write(app.IMU, str, "char");
            
        end

        % Value changed function: IntegralSlider
        function IntegralSliderValueChanged(app, event)
            value = app.IntegralSlider.Value;
            str = sprintf("32%1.2f", value);
            write(app.IMU, str, "char");
            
        end

        % Value changed function: DerivativeSlider
        function DerivativeSliderValueChanged(app, event)
            value = app.DerivativeSlider.Value;
            str = sprintf("33%3.0f", value);
            write(app.IMU, str, "char");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PortDropDownLabel = uilabel(app.LeftPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.LeftPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PortDropDownOpening, true);
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.ProportionalSliderLabel = uilabel(app.LeftPanel);
            app.ProportionalSliderLabel.Enable = 'off';
            app.ProportionalSliderLabel.Text = {'Proportional'; ''};
            app.ProportionalSlider = uislider(app.LeftPanel);
            app.ProportionalSlider.Limits = [0 2];
            app.ProportionalSlider.ValueChangedFcn = createCallbackFcn(app, @ProportionalSliderValueChanged, true);
            app.ProportionalSlider.ValueChangingFcn = createCallbackFcn(app, @ProportionalSliderValueChanging, true);
            app.ProportionalSlider.Enable = 'off';
            app.ProportionalSlider.Value = 1.3887;
            app.ProportionalEdit = uieditfield(app.LeftPanel, 'numeric');
            app.ProportionalEdit.Limits = [0 2];
            app.ProportionalEdit.ValueDisplayFormat = '%1.2f';
            app.ProportionalEdit.ValueChangedFcn = createCallbackFcn(app, @ProportionalEditValueChanged, true);
            app.ProportionalEdit.Enable = 'off';
            app.ProportionalEdit.Value = 1.2635;
            app.IntegralEdit = uieditfield(app.LeftPanel, 'numeric');
            app.IntegralEdit.Limits = [0 2];
            app.IntegralEdit.ValueDisplayFormat = '%1.2f';
            app.IntegralEdit.ValueChangedFcn = createCallbackFcn(app, @IntegralEditValueChanged, true);
            app.IntegralEdit.Enable = 'off';
            app.IntegralEdit.Value = 2;
            app.IntegralSliderLabel = uilabel(app.LeftPanel);
            app.IntegralSliderLabel.Enable = 'off';
            app.IntegralSliderLabel.Text = 'Integral';
            app.IntegralSlider = uislider(app.LeftPanel);
            app.IntegralSlider.Limits = [0 2];
            app.IntegralSlider.ValueChangedFcn = createCallbackFcn(app, @IntegralSliderValueChanged, true);
            app.IntegralSlider.ValueChangingFcn = createCallbackFcn(app, @IntegralSliderValueChanging, true);
            app.IntegralSlider.Enable = 'off';
            app.IntegralSlider.Value = 1;
            app.DerivativeEdit = uieditfield(app.LeftPanel, 'numeric');
            app.DerivativeEdit.Limits = [0.1 2];
            app.DerivativeEdit.ValueDisplayFormat = '%1.2f';
            app.DerivativeEdit.ValueChangedFcn = createCallbackFcn(app, @DerivativeEditValueChanged, true);
            app.DerivativeEdit.Enable = 'off';
            app.DerivativeEdit.Value = 0.3;
            app.DerivativeSliderLabel = uilabel(app.LeftPanel);
            app.DerivativeSliderLabel.Enable = 'off';
            app.DerivativeSliderLabel.Text = 'Derivative';
            app.DerivativeSlider = uislider(app.LeftPanel);
            app.DerivativeSlider.Limits = [0.1 2];
            app.DerivativeSlider.ValueChangedFcn = createCallbackFcn(app, @DerivativeSliderValueChanged, true);
            app.DerivativeSlider.ValueChangingFcn = createCallbackFcn(app, @DerivativeSliderValueChanging, true);
            app.DerivativeSlider.Enable = 'off';
            app.DerivativeSlider.Value = 0.5;
            app.AngleEdit = uieditfield(app.LeftPanel, 'numeric');
            app.AngleEdit.Limits = [0 180];
            app.AngleEdit.ValueDisplayFormat = '%3.0f';
            app.AngleEdit.ValueChangedFcn = createCallbackFcn(app, @AngleEditValueChanged, true);
            app.AngleEdit.Enable = 'off';
            app.AngleEdit.Value = 90;
            app.AngleSliderLabel = uilabel(app.LeftPanel);
            app.AngleSliderLabel.Enable = 'off';
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.LeftPanel);
            app.AngleSlider.Limits = [0 180];
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @AngleSliderValueChanged, true);
            app.AngleSlider.ValueChangingFcn = createCallbackFcn(app, @AngleSliderValueChanging, true);
            app.AngleSlider.Enable = 'off';
            app.AngleSlider.Value = 90;
            app.RestartButton = uibutton(app.LeftPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Enable = 'off';
            app.RestartButton.Text = 'Restart';
            app.StartStopSwitch = uiswitch(app.LeftPanel, 'slider');
            app.StartStopSwitch.Items = {'Stop', 'Run'};
            app.StartStopSwitch.ValueChangedFcn = createCallbackFcn(app, @StartStopSwitchValueChanged, true);
            app.StartStopSwitch.Enable = 'off';
            app.StartStopSwitch.Value = 'Stop';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Угол')
            xlabel(app.UIAxes, 'Время, с')
            ylabel(app.UIAxes, 'Угол, град')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 180];
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Компоненты ПИД')
            xlabel(app.UIAxes_2, 'Время, с')
            ylabel(app.UIAxes_2, 'Значение')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 1];
            app.UIAxes_2.YLim = [-3 3];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end